classdef PeakTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        InitializeParametersLabel       matlab.ui.control.Label
        BEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        vEditFieldLabel                 matlab.ui.control.Label
        vEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        kLEditFieldLabel                matlab.ui.control.Label
        kLEditField                     matlab.ui.control.NumericEditField
        neffEditFieldLabel              matlab.ui.control.Label
        neffEditField                   matlab.ui.control.NumericEditField
        dneffEditFieldLabel             matlab.ui.control.Label
        dneffEditField                  matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        BraggWavelengthLabel            matlab.ui.control.Label
        WavelengthgridLabel             matlab.ui.control.Label
        GratingStrengthLabel            matlab.ui.control.Label
        GratingLengthLabel              matlab.ui.control.Label
        FringevisibilityLabel           matlab.ui.control.Label
        EffectiverefractiveindexLabel   matlab.ui.control.Label
        ModulationamplitudeofneffLabel  matlab.ui.control.Label
        SignaltoNoiseRatioLabel         matlab.ui.control.Label
        InitializeButton                matlab.ui.control.Button
        SetDefaultValuesButton          matlab.ui.control.Button
        FromEditFieldLabel              matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        dLabel                          matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        ResolutionLabel                 matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TrackingMethodDropDownLabel     matlab.ui.control.Label
        TrackingMethodDropDown          matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        ParameterEditFieldLabel         matlab.ui.control.Label
        ParameterEditField              matlab.ui.control.NumericEditField
        PlotButton                      matlab.ui.control.Button
        DenoisingwithWaveletCheckBox    matlab.ui.control.CheckBox
        ResamplingRateQEditFieldLabel   matlab.ui.control.Label
        ResamplingRateQEditField        matlab.ui.control.NumericEditField
        ResamplingCheckBox              matlab.ui.control.CheckBox
        ChoosetheoutputButtonGroup      matlab.ui.container.ButtonGroup
        BraggWavelengthButton           matlab.ui.control.RadioButton
        WavelengthShiftButton           matlab.ui.control.RadioButton
        XivalueButton                   matlab.ui.control.RadioButton
        ResultEditFieldLabel            matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.NumericEditField
        ReflectivityindBCheckBox        matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        lambdaB; % Bragg wavelength 
        lambda; %Wavelength axis with 1pm wavelength resolution 
        kL; % Grating strength
        L; % Grating length 
        v;  % Fringe visibility 
        neff; % Effective refractive index 
        dneff; % Modulation amplitude of refractive index 
        SNR;  %Signal to Noise ratio 
        
        parameter; %Parameter for exact methods of peak tracking (could be power of higher order or treshhold for X_BW)
        
        Q; %resampling Rate
        xi; %xi value for KLT
        
        MethodName;     %name of peak tracking technique
        delta_lambda;   %wavelength shift
        Est_lambdaB;    %estimated Bragg wavelength using Tracking methods
        
        RMSE; %Root Mean Square Error
        NoiseReflectivity; %reflectivity function of lambda with noise
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if app.ReflectivityindBCheckBox.Value == 0
                try
                Reflectivity_ref = FBGspectrum(app.lambdaB, app.lambda, app.kL, app.L, app.dneff, app.neff, app.v);
                FBG = tanh(app.kL).^2;
                variance = max(FBG)/ 10.^(app.SNR/10); 
                noise = sqrt(variance).* randn(1, length(app.lambda));
                NoiseReflectivity_ref = Reflectivity_ref + noise;
            
                plot(app.UIAxes, app.lambda, NoiseReflectivity_ref, 'b' ,'LineWidth' , 1 )
                hold (app.UIAxes, 'on')
                plot(app.UIAxes, app.lambda, Reflectivity_ref, 'r' ,'LineWidth' , 1 )
                hold (app.UIAxes, 'on')
            
                app.delta_lambda = -20e-12+(20e-12 + 80e-12)*rand(1,1);
                lambdaBB = app.lambdaB + app.delta_lambda;
                Reflectivity = FBGspectrum(lambdaBB, app.lambda, app.kL, app.L, app.dneff, app.neff, app.v);
                app.NoiseReflectivity = Reflectivity + noise;
            
                switch app.MethodName
                    case "Maximum"
                        app.Est_lambdaB = Maximum(app.NoiseReflectivity, app.lambda);
                    case "X_BW"
                        app.Est_lambdaB = X_BW(app.NoiseReflectivity, app.lambda,app.parameter);
                    case "Centroid"
                        app.Est_lambdaB = Centroid(app.NoiseReflectivity, app.lambda);
                    case "SecondOrderPolynomial"
                        app.Est_lambdaB = SecondOrderPolynomial(app.NoiseReflectivity, app.lambda);
                    case "GaussianPolynomialFit"
                        app.Est_lambdaB = GaussianPolynomialFit(app.NoiseReflectivity, app.lambda);
                    case "HigherOrderPolynomial"
                        app.Est_lambdaB = HigherOrderPolynomial(app.NoiseReflectivity, app.lambda, app.parameter);
                    case "Spline"
                        app.Est_lambdaB = Spline(app.NoiseReflectivity, app.lambda);
                    case "KLT" 
                        app.xi = KLT(app.NoiseReflectivity, app.lambda);
                end
                plot(app.UIAxes, app.lambda, app.NoiseReflectivity, 'g' ,'LineWidth' , 1 )
                hold (app.UIAxes, 'on')
                plot(app.UIAxes, app.lambda, Reflectivity, 'y' ,'LineWidth' , 1 )
                legend(app.UIAxes,'Reference Reflectivity with noise', 'Reference Reflectivity', ...
                    'Shifted Reflectivity with noise','Shifted Reflectivity')
                hold (app.UIAxes, 'off')
            
                catch ME
                    str = sprintf(['Something is wrong with the plotting a code. \n' ...
                         'Try to choose the peak tracking method and plot agian\n'''...
                        'Matlab says: \n' ME.message]);
                    uialert(app.UIFigure,str,'Plotting Problem')
                end
                %Reflectivity in dB
             else 
                try
                    Reflectivity_ref = FBGspectrum(app.lambdaB, app.lambda, app.kL, app.L, app.dneff, app.neff, app.v);
                    FBG = tanh(app.kL).^2;
                    variance = max(FBG)/ 10.^(app.SNR/10); 
                    noise = sqrt(variance).* randn(1, length(app.lambda));
                    NoiseReflectivity_ref = Reflectivity_ref + noise;
                    Reflectivity_ref_dB = 10*log(Reflectivity_ref);
                    NoiseReflectivity_ref_dB = 10*log(NoiseReflectivity_ref);
                    
                    plot(app.UIAxes, app.lambda, NoiseReflectivity_ref_dB, 'b' ,'LineWidth' , 1 )
                    hold (app.UIAxes, 'on')
                    plot(app.UIAxes, app.lambda, Reflectivity_ref_dB, 'r' ,'LineWidth' , 1 )
                    hold (app.UIAxes, 'on')
                
                    app.delta_lambda = -20e-12+(20e-12 + 80e-12)*rand(1,1);
                    lambdaBB = app.lambdaB + app.delta_lambda;
                    Reflectivity = FBGspectrum(lambdaBB, app.lambda, app.kL, app.L, app.dneff, app.neff, app.v);
                    app.NoiseReflectivity = Reflectivity + noise;         
                    
                    switch app.MethodName
                        case "Maximum"
                            app.Est_lambdaB = Maximum(app.NoiseReflectivity, app.lambda);
                        case "X_BW"
                            app.Est_lambdaB = X_BW(app.NoiseReflectivity, app.lambda,app.parameter);
                        case "Centroid"
                        app.Est_lambdaB = Centroid(app.NoiseReflectivity, app.lambda);
                        case "SecondOrderPolynomial"
                            app.Est_lambdaB = SecondOrderPolynomial(app.NoiseReflectivity, app.lambda);
                        case "GaussianPolynomialFit"
                        app.Est_lambdaB = GaussianPolynomialFit(app.NoiseReflectivity, app.lambda);
                        case "HigherOrderPolynomial"
                            app.Est_lambdaB = HigherOrderPolynomial(app.NoiseReflectivity, app.lambda, app.parameter);
                        case "Spline"
                            app.Est_lambdaB = Spline(app.NoiseReflectivity, app.lambda);
                        case "KLT" 
                            app.xi = KLT(app.NoiseReflectivity, app.lambda);
                    end
                    
                    Reflectivity = 10*log(Reflectivity);
                    app.NoiseReflectivity = 10*log(app.NoiseReflectivity);
                    plot(app.UIAxes, app.lambda, app.NoiseReflectivity, 'g' ,'LineWidth' , 1 )
                    hold (app.UIAxes, 'on')
                    plot(app.UIAxes, app.lambda, Reflectivity, 'y' ,'LineWidth' , 1 )
                    legend(app.UIAxes,'Reference Reflectivity with noise', 'Reference Reflectivity', ...
                    'Shifted Reflectivity with noise','Shifted Reflectivity')
                    hold (app.UIAxes, 'off')
                
                catch ME
                    str = sprintf(['Something is wrong with the plotting a code. \n' ...
                     'Try to choose the peak tracking method and plot agian\n'''...
                    'Matlab says: \n' ME.message]);
                    uialert(app.UIFigure,str,'Plotting Problem')
                end
            end  
        end

        % Value changed function: TrackingMethodDropDown
        function TrackingMethodDropDownValueChanged(app, event)
            app.MethodName = app.TrackingMethodDropDown.Value;
            switch app.MethodName
                case "Maximum"
                    app.ParameterEditField.Enable = 0;
                    app.ParameterEditFieldLabel.Enable = 0;
                    app.BraggWavelengthButton.Enable = 1;
                    app.XivalueButton.Enable = 0;
                case "X_BW"
                    app.ParameterEditField.Enable = 1;
                    app.ParameterEditFieldLabel.Enable = 1;
                    app.BraggWavelengthButton.Enable = 1;
                    app.XivalueButton.Enable = 0;
                case "Centroid"
                    app.ParameterEditField.Enable = 0;
                    app.ParameterEditFieldLabel.Enable = 0;
                    app.BraggWavelengthButton.Enable = 1;
                    app.XivalueButton.Enable = 0;
                case "SecondOrderPolynomial"
                    app.ParameterEditField.Enable = 0;
                    app.ParameterEditFieldLabel.Enable = 0;
                    app.BraggWavelengthButton.Enable = 1;
                    app.XivalueButton.Enable = 0;
                case "GaussianPolynomialFit"
                    app.ParameterEditField.Enable = 0;
                    app.ParameterEditFieldLabel.Enable = 0;
                    app.BraggWavelengthButton.Enable = 1;
                    app.XivalueButton.Enable = 0;
                case "HigherOrderPolynomial"
                    app.ParameterEditField.Enable = 1;
                    app.ParameterEditFieldLabel.Enable = 1;
                    app.BraggWavelengthButton.Enable = 1;
                    app.XivalueButton.Enable = 0;
                case "Spline"
                    app.ParameterEditField.Enable = 0;
                    app.ParameterEditFieldLabel.Enable = 0;
                    app.BraggWavelengthButton.Enable = 1;
                    app.XivalueButton.Enable = 0;
                case "KLT" 
                    app.ParameterEditField.Enable = 0;
                    app.ParameterEditFieldLabel.Enable = 0;
                    app.BraggWavelengthButton.Enable = 0;
                    app.XivalueButton.Enable = 1;
                otherwise
                    app.ParameterEditField.Enable = 0;
                    app.ParameterEditFieldLabel.Enable = 0;
                    app.BraggWavelengthButton.Enable = 1;
                    app.XivalueButton.Enable = 0;
            end
            
        end

        % Value changed function: ResamplingCheckBox
        function ResamplingCheckBoxValueChanged(app, event)
            value = app.ResamplingCheckBox.Value;
            if value == 1
                app.ResamplingRateQEditField.Enable = 1;
                app.ResamplingRateQEditFieldLabel.Enable = 1;
                app.Q = app.ResamplingRateQEditField.Value;
                if app.Q > 0
                    app.lambda = Resampling(app.Q, app.lambda);
                end
            else 
                app.ResamplingRateQEditField.Enable = 0;
                app.ResamplingRateQEditFieldLabel.Enable = 0;
            end            
        end

        % Selection changed function: ChoosetheoutputButtonGroup
        function ChoosetheoutputButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChoosetheoutputButtonGroup.SelectedObject;
            switch selectedButton
                case app.BraggWavelengthButton
                    app.ResultEditField.Value = app.Est_lambdaB;
                case app.WavelengthShiftButton 
                    app.ResultEditField.Value = app.delta_lambda;
                case app.XivalueButton
                    app.ResultEditField.Value = max(app.xi);
            end
        end

        % Button pushed function: SetDefaultValuesButton
        function SetDefaultValuesButtonPushed(app, event)
            app.BEditField.Value = 1550e-9;
            app.FromEditField.Value = 1548e-9;
            app.ToEditField.Value = 1552e-9;
            app.dEditField.Value = 10e-12;
            app.kLEditField.Value = 2.3;
            app.LEditField.Value = 0.01; 
            app.vEditField.Value = 1;  
            app.neffEditField.Value = 1.5; 
            app.dneffEditField.Value = 1e-5; 
            app.SNREditField.Value = 30;
            drawnow();
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
             app.lambdaB = app.BEditField.Value;
             app.lambda = app.FromEditField.Value : app.dEditField.Value : app.ToEditField.Value; 
             app.kL = app.kLEditField.Value;
             app.L = app.LEditField.Value; 
             app.v = app.vEditField.Value;  
             app.neff = app.neffEditField.Value;
             app.dneff = app.dneffEditField.Value; 
             app.SNR = app.SNREditField.Value;
        end

        % Value changed function: DenoisingwithWaveletCheckBox
        function DenoisingwithWaveletCheckBoxValueChanged(app, event)
            value = app.DenoisingwithWaveletCheckBox.Value;
            if value == 1
                dwtmode('per', 'nodisplay'); 
                wname='sym6'; 
                level = 5; 
                app.NoiseReflectivity = wden(app.NoiseReflectivity, 'rigrsure', 's', 'sln', level, wname);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {566, 566};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InitializeParametersLabel = uilabel(app.LeftPanel);
            app.InitializeParametersLabel.Text = 'Initialize Parameters';
            app.BEditFieldLabel = uilabel(app.LeftPanel);
            app.BEditFieldLabel.Text = 'λB';
            app.BEditField = uieditfield(app.LeftPanel, 'numeric');
            app.vEditFieldLabel = uilabel(app.LeftPanel);
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LEditFieldLabel = uilabel(app.LeftPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.LeftPanel, 'numeric');
            app.kLEditFieldLabel = uilabel(app.LeftPanel);
            app.kLEditFieldLabel.Text = 'kL';
            app.kLEditField = uieditfield(app.LeftPanel, 'numeric');
            app.neffEditFieldLabel = uilabel(app.LeftPanel);
            app.neffEditFieldLabel.Text = 'neff';
            app.neffEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dneffEditFieldLabel = uilabel(app.LeftPanel);
            app.dneffEditFieldLabel.Text = 'dneff';
            app.dneffEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SNREditFieldLabel = uilabel(app.LeftPanel);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.LeftPanel, 'numeric');
            app.BraggWavelengthLabel = uilabel(app.LeftPanel);
            app.BraggWavelengthLabel.Text = 'Bragg  Wavelength';
            app.WavelengthgridLabel = uilabel(app.LeftPanel);
            app.WavelengthgridLabel.Text = 'Wavelength grid, λ';
            app.GratingStrengthLabel = uilabel(app.LeftPanel);
            app.GratingStrengthLabel.Text = 'Grating Strength';
            app.GratingLengthLabel = uilabel(app.LeftPanel);
            app.GratingLengthLabel.Text = 'Grating Length ';
            app.FringevisibilityLabel = uilabel(app.LeftPanel);
            app.FringevisibilityLabel.Text = 'Fringe visibility';
            app.EffectiverefractiveindexLabel = uilabel(app.LeftPanel);
            app.EffectiverefractiveindexLabel.Text = 'Effective refractive index';
            app.ModulationamplitudeofneffLabel = uilabel(app.LeftPanel);
            app.ModulationamplitudeofneffLabel.Text = 'Modulation amplitude of neff';
            app.SignaltoNoiseRatioLabel = uilabel(app.LeftPanel);
            app.SignaltoNoiseRatioLabel.Text = 'Signal to Noise Ratio';
            app.InitializeButton = uibutton(app.LeftPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.SetDefaultValuesButton = uibutton(app.LeftPanel, 'push');
            app.SetDefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultValuesButtonPushed, true);
            app.SetDefaultValuesButton.Text = 'Set Default Values';
            app.FromEditFieldLabel = uilabel(app.LeftPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ToEditFieldLabel = uilabel(app.LeftPanel);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.LeftPanel, 'numeric');
            app.dLabel = uilabel(app.LeftPanel);
            app.dLabel.Text = 'dλ';
            app.dEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ResolutionLabel = uilabel(app.LeftPanel);
            app.ResolutionLabel.Text = 'Resolution';
            app.RightPanel = uipanel(app.GridLayout);
            app.TrackingMethodDropDownLabel = uilabel(app.RightPanel);
            app.TrackingMethodDropDownLabel.Text = 'Tracking Method';
            app.TrackingMethodDropDown = uidropdown(app.RightPanel);
            app.TrackingMethodDropDown.Items = {'Select...', 'Maximum', 'X_BW', 'Centroid', 'SecondOrderPolynomial', 'GaussianPolynomialFit', 'HigherOrderPolynomial', 'Spline', 'KLT', '', ''};
            app.TrackingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackingMethodDropDownValueChanged, true);
            app.TrackingMethodDropDown.Value = 'Select...';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'FBG Spectrum')
            xlabel(app.UIAxes, 'λ')
            ylabel(app.UIAxes, 'Reflectivity')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ParameterEditFieldLabel = uilabel(app.RightPanel);
            app.ParameterEditFieldLabel.Enable = 'off';
            app.ParameterEditFieldLabel.Text = 'Parameter';
            app.ParameterEditField = uieditfield(app.RightPanel, 'numeric');
            app.ParameterEditField.Enable = 'off';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DenoisingwithWaveletCheckBox = uicheckbox(app.RightPanel);
            app.DenoisingwithWaveletCheckBox.ValueChangedFcn = createCallbackFcn(app, @DenoisingwithWaveletCheckBoxValueChanged, true);
            app.DenoisingwithWaveletCheckBox.Text = 'Denoising with Wavelet';
            app.ResamplingRateQEditFieldLabel = uilabel(app.RightPanel);
            app.ResamplingRateQEditFieldLabel.Enable = 'off';
            app.ResamplingRateQEditFieldLabel.Text = 'Resampling Rate Q';
            app.ResamplingRateQEditField = uieditfield(app.RightPanel, 'numeric');
            app.ResamplingRateQEditField.Enable = 'off';
            app.ResamplingCheckBox = uicheckbox(app.RightPanel);
            app.ResamplingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResamplingCheckBoxValueChanged, true);
            app.ResamplingCheckBox.Text = 'Resampling';
            app.ChoosetheoutputButtonGroup = uibuttongroup(app.RightPanel);
            app.ChoosetheoutputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosetheoutputButtonGroupSelectionChanged, true);
            app.ChoosetheoutputButtonGroup.Title = 'Choose the output';
            app.BraggWavelengthButton = uiradiobutton(app.ChoosetheoutputButtonGroup);
            app.BraggWavelengthButton.Text = 'Bragg Wavelength';
            app.BraggWavelengthButton.Value = true;
            app.WavelengthShiftButton = uiradiobutton(app.ChoosetheoutputButtonGroup);
            app.WavelengthShiftButton.Text = 'Wavelength Shift';
            app.XivalueButton = uiradiobutton(app.ChoosetheoutputButtonGroup);
            app.XivalueButton.Text = 'Xi  value ';
            app.ResultEditFieldLabel = uilabel(app.RightPanel);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.RightPanel, 'numeric');
            app.ReflectivityindBCheckBox = uicheckbox(app.RightPanel);
            app.ReflectivityindBCheckBox.Text = 'Reflectivity in dB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PeakTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end